classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WritePositionEditField       matlab.ui.control.EditField
        WritePositionEditFieldLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        ConnecttoArduinoButton       matlab.ui.control.Button
        SetPostionButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduino; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetPostionButton
        function SetPostionButtonPushed(app, event)


position = app.WritePositionEditField.Value;
writeline(app.arduino, position);


% Wait for a moment to ensure Arduino processes the data
pause(2);

% Close the serial connection
clear app.arduino;
delete app.arduino;
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            app.arduino= serialport("COM6", 115200);
            configureTerminator(app.arduino, "LF");
            flush(app.arduino);
            if isvalid(app.arduino)
                app.EditField.Value = "                connected";
            else 
                app.EditField.Value = "                 Error";
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetPostionButton = uibutton(app.UIFigure, 'push');
            app.SetPostionButton.ButtonPushedFcn = createCallbackFcn(app, @SetPostionButtonPushed, true);
            app.SetPostionButton.Text = 'Set Postion';
            app.ConnecttoArduinoButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.WritePositionEditFieldLabel = uilabel(app.UIFigure);
            app.WritePositionEditFieldLabel.Text = 'Write Position';
            app.WritePositionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end